classdef GUI_prostownik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIprostownikUIFigure   matlab.ui.Figure
        UAxes                   matlab.ui.control.UIAxes
        IAxes                   matlab.ui.control.UIAxes
        VoVLabel                matlab.ui.control.Label
        VoEditField             matlab.ui.control.NumericEditField
        fHzLabel                matlab.ui.control.Label
        fEditField              matlab.ui.control.NumericEditField
        NoEditFieldLabel        matlab.ui.control.Label
        NoEditField             matlab.ui.control.NumericEditField
        delta_tsEditFieldLabel  matlab.ui.control.Label
        delta_tEditField        matlab.ui.control.NumericEditField
        RYSUJButton             matlab.ui.control.Button
        ROmEditFieldLabel       matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        CFLabel                 matlab.ui.control.Label
        CEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        R = [];
        Vo = [];
        f = [];
        No = [];
        delta_t = [];
        C = [];  
        
        Vs = [];
        Vr = [];
        Ic = [];
        Ir = [];
        Id = [];
        t = [];
        t_off = [];
        t_on = [];
        idx_t_off = [];
        idx_t_on = [];
        
        Usr = [];
        Ut = [];
        kt = [];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UAxes.Toolbar = "off";
            %app.IAxes.Toolbar = "off";
            
        end

        % Button pushed function: RYSUJButton
        function RYSUJButtonPushed(app, event)
            app.RYSUJButton.Enable = "off"; 
            
            app.R = app.REditField.Value;
            app.C = app.CEditField.Value;
            app.Vo = app.VoEditField.Value;
            app.f = app.fEditField.Value;
            app.No = app.NoEditField.Value;
            app.delta_t = app.delta_tEditField.Value;
            
            if(app.R < 10) || (app.R > 10e7)   
                uialert(app.GUIprostownikUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
            elseif(app.C < 10e-9) || (app.C > 10e-3) 
                uialert(app.GUIprostownikUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
            else
                app.UAxes.cla;
                app.IAxes.cla;
                
                app.t = 0:app.delta_t:(app.No/app.f);
                [app.Vs,app.Vr,app.Ic,app.Ir,app.Id,app.t_off,app.t_on] = prostownik1(app.R,app.C,app.Vo,app.f,app.No,app.delta_t);
                [~,app.idx_t_off] = intersect(app.t,app.t_off);
                [~,app.idx_t_on] = intersect(app.t,app.t_on);
                
                hold(app.UAxes,"on");
                plot(app.UAxes,app.t,app.Vs,'b--');
                plot(app.UAxes,app.t,app.Vr,'r');
                plot(app.UAxes,app.t_off,app.Vs(app.idx_t_off),'m*');
                %plot(app.UAxes,app.t_on,app.Vs(app.idx_t_on),'g*');
                plot(app.UAxes,app.t_off,app.Vr(app.idx_t_off),'m*');
                %plot(app.UAxes,app.t_on,app.Vr(app.idx_t_on),'g*');
                hold(app.UAxes,"off");
                %legend({'Vs','Vr','t_off','t_on'},'Location','southeast');
                legend(app.UAxes,{'Vs','Vr','t-off'},'Location','southeast');
                
                hold(app.IAxes,"on");
                plot(app.IAxes,app.t,app.Ir,'r');
                plot(app.IAxes,app.t,app.Ic,'k');
                plot(app.IAxes,app.t,app.Id,'b');
                plot(app.IAxes,app.t_off,app.Ir(app.idx_t_off),'m*');
                %plot(app.IAxes,app.t_on,app.Ir(app.idx_t_on),'g*');
                plot(app.IAxes,app.t_off,app.Ic(app.idx_t_off),'m*');
                %plot(app.IAxes,app.t_on,app.Ic(app.idx_t_on),'g*');
                plot(app.IAxes,app.t_off,app.Id(app.idx_t_off),'m*');
                %plot(app.IAxes,app.t_on,app.Id(app.idx_t_on),'g*');
                hold(app.IAxes,"off")
                %legend(app.IAxes,{'Ir','Ic','Id','t_off','t_on'},'Location','southeast');
                legend(app.IAxes,{'Ir','Ic','Id','t-off'},'Location','southeast');
                
                app.Usr = sum(app.Vr(app.idx_t_off(1):app.idx_t_off(2)))/(app.idx_t_off(2)-app.idx_t_off(1)+1);
                app.Ut = (max(app.Vr(app.idx_t_off(1):app.idx_t_off(2)))-min(app.Vr(app.idx_t_off(1):app.idx_t_off(2))))/2;
                app.kt = app.Ut/app.Usr;
                uialert(app.GUIprostownikUIFigure,['Dla przedzialu czasu od t_a do t_d napiecie srednie wynosi '...
                    , num2str(app.Usr),'[V], amplituda tetnien wynosi ', num2str(app.Ut),...
                    '[V] oraz wspolczynnik tetnien wynosi ', num2str(app.kt),'.'],'Otrzymane wyniki:',...
                    'Icon','info');
            end
            
            app.RYSUJButton.Enable = "on"; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIprostownikUIFigure = uifigure('Visible', 'off');
            app.GUIprostownikUIFigure.Name = 'GUI prostownik';
            app.UAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.UAxes, 'Napiecie')
            xlabel(app.UAxes, 't [s]')
            ylabel(app.UAxes, 'U [V]')
            app.IAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.IAxes, 'Natezenie')
            xlabel(app.IAxes, 't [s]')
            ylabel(app.IAxes, 'I [A]')
            app.VoVLabel = uilabel(app.GUIprostownikUIFigure);
            app.VoVLabel.Text = 'Vo [V]';
            app.VoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.VoEditField.Value = 12;
            app.fHzLabel = uilabel(app.GUIprostownikUIFigure);
            app.fHzLabel.Text = 'f [Hz]';
            app.fEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.fEditField.Value = 50;
            app.NoEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.NoEditFieldLabel.Text = 'No';
            app.NoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.NoEditField.Value = 3;
            app.delta_tsEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.delta_tsEditFieldLabel.Text = 'delta_t [s]';
            app.delta_tEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.delta_tEditField.Value = 5e-05;
            app.RYSUJButton = uibutton(app.GUIprostownikUIFigure, 'push');
            app.RYSUJButton.ButtonPushedFcn = createCallbackFcn(app, @RYSUJButtonPushed, true);
            app.RYSUJButton.Text = 'RYSUJ';
            app.ROmEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.ROmEditFieldLabel.Text = 'R [Om]';
            app.REditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.REditField.Value = 820;
            app.CFLabel = uilabel(app.GUIprostownikUIFigure);
            app.CFLabel.Text = 'C [F]';
            app.CEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.CEditField.Value = 4.7e-05;
            % Show the figure after all components are created
            app.GUIprostownikUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_prostownik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIprostownikUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIprostownikUIFigure)
        end
    end
end