classdef GUI_prostownik_animacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIprostownikanimacjaUIFigure  matlab.ui.Figure
        UAxes                          matlab.ui.control.UIAxes
        IAxes                          matlab.ui.control.UIAxes
        VoVLabel                       matlab.ui.control.Label
        VoEditField                    matlab.ui.control.NumericEditField
        fHzLabel                       matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        NoEditFieldLabel               matlab.ui.control.Label
        NoEditField                    matlab.ui.control.NumericEditField
        delta_tsEditFieldLabel         matlab.ui.control.Label
        delta_tEditField               matlab.ui.control.NumericEditField
        STARTButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        ROmEditFieldLabel              matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        CFLabel                        matlab.ui.control.Label
        CEditField                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        R = [];
        Vo = [];
        f = [];
        No = [];
        delta_t = [];
        C = [];  
        
        w = [];
        
        Vs = [];
        Vr = [];
        Ic = [];
        Ir = [];
        Id = [];
        t = [];
        t_a = [];
        idx_t_a = [];
        
        wspolrzednaKursora = [];
        stopSymulacja = false;
        aktualne_t = 0;  
        
        licznik = 1;
    end
    
    methods (Access = private)
        
        function rysujKursor(app)
            %app.UAxes.cla;
            hold(app.UAxes,"on");
            %plot(app.UAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(app.Vo,-app.Vo,20),'k--','HandleVisibility','off');
            plot(app.UAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(app.Vo,-app.Vo,20),'k--');
            hold(app.UAxes,"off"); 
            
            %app.IAxes.cla;
            natezenieMaxWartosc = 2*pi*app.f*app.C*app.Vo;
            hold(app.IAxes,"on");
            %plot(app.IAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(natezenieMaxWartosc,-natezenieMaxWartosc,20),'k--','HandleVisibility','off');
            plot(app.IAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(natezenieMaxWartosc,-natezenieMaxWartosc,20),'k--');
            hold(app.IAxes,"off"); 
        end
        
        function czytajParametryZGui(app)
            app.R = app.REditField.Value;
            app.C = app.CEditField.Value;
            app.Vo = app.VoEditField.Value;
            app.f = app.fEditField.Value;
            app.No = app.NoEditField.Value;
            app.delta_t = app.delta_tEditField.Value;
        end
        
        function liczProstownik(app) 
            app.t(app.licznik) = app.aktualne_t;
            app.Ir(app.licznik) = app.Vo*sin(app.w*app.t(app.licznik))/app.R;
            app.Ic(app.licznik) = app.w*app.C*app.Vo*cos(app.w*app.t(app.licznik));
            %app.Id(app.licznik) = app.Ir(app.licznik)+app.Ic(app.licznik);
            app.Vs(app.licznik) = app.Vo*sin(app.w*app.t(app.licznik));
            if(app.Ir(app.licznik) <= -app.Ic(app.licznik)) && ~(app.Ir(app.licznik-1) <= -app.Ic(app.licznik-1))
                app.t_a(end+1) = app.t(app.licznik);
            end
            if(app.Ir(app.licznik) > -app.Ic(app.licznik)) && (app.Vs(app.licznik-1) >= app.Vr(app.licznik-1))
                app.Vr(app.licznik) = app.Vo*sin(app.w*app.t(app.licznik));
            else
                app.Vr(app.licznik) = app.Vo*sin(app.w*app.t_a(end))*exp((app.t_a(end)-app.t(app.licznik))/(app.R*app.C)); 
            end        
            
            [~,app.idx_t_a] = intersect(app.t,app.t_a);            
        end
        
        function rysujWykresy(app) 
            app.wspolrzednaKursora = app.aktualne_t;
            app.UAxes.cla;
            hold(app.UAxes,"on");
            plot(app.UAxes,app.t,app.Vs,'b--');
            plot(app.UAxes,app.t,app.Vr,'r');
            plot(app.UAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(app.Vo,-app.Vo,20),'k--');
            plot(app.UAxes,app.t_a,app.Vs(app.idx_t_a),'m*');
            plot(app.UAxes,app.t_a,app.Vr(app.idx_t_a),'m*');            
            hold(app.UAxes,"off");
            legend(app.UAxes,{'Vs','Vr','kursor','t_a'},'Location','southeast');

            natezenieMaxWartosc = 2*pi*app.f*app.C*app.Vo;

            app.IAxes.cla;
            hold(app.IAxes,"on");
            plot(app.IAxes,app.t,app.Ir,'r');
            plot(app.IAxes,app.t,app.Ic,'g');
            %plot(app.IAxes,app.t,app.Id,'c');
            plot(app.IAxes,linspace(app.wspolrzednaKursora,app.wspolrzednaKursora,20),linspace(natezenieMaxWartosc,-natezenieMaxWartosc,20),'k--');
            plot(app.IAxes,app.t_a,app.Ir(app.idx_t_a),'m*');
            plot(app.IAxes,app.t_a,app.Ic(app.idx_t_a),'m*');
            %plot(app.IAxes,app.t_a,app.Id(app.idx_t_a),'m*');
            %legend(app.IAxes,{'Ir','Ic','Id','t_a'},'Location','southeast');
            legend(app.IAxes,{'Ir','Ic','kursor','t_a'},'Location','southeast');
            hold(app.IAxes,"off"); 
            
%             app.wspolrzednaKursora = app.aktualne_t;
%             rysujKursor(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UAxes.Toolbar = "off";
            %app.IAxes.Toolbar = "off";
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable = "off"; 
            
            czytajParametryZGui(app);
                        
            if(app.R < 10) || (app.R > 10e7)   
                uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
            elseif(app.C < 10e-9) || (app.C > 10e-3) 
                uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
            else
                %obliczyc wartosci dla pierwszej iteracji
%                 if(floor(app.No/(app.f*app.delta_t))+1 <= length(app.t)-1)
%                     tmp = app.t;
%                     app.t = 0:app.delta_t:(app.No/app.f);
%                     app.t(2:1+length(tmp)) = tmp; 
%                 end
                app.aktualne_t = 0;
                app.licznik = 1;
                
                app.t(1) = app.aktualne_t;
                app.w = 2*pi*app.f;                
                app.Ir(1) = app.Vo*sin(app.w*app.t(1))/app.R;
                app.Ic(1) = app.w*app.C*app.Vo*cos(app.w*app.t(1));
                app.Vs(1) = app.Vo*sin(app.w*app.t(1));
                app.Vr(1) = app.Vo*sin(app.w*app.t(1));                
                
                rysujWykresy(app); 
                
                while(~app.stopSymulacja)
                    drawnow;
                    app.licznik = app.licznik + 1;
                    app.aktualne_t = app.aktualne_t + app.delta_t;
                    if(app.aktualne_t > (app.No/app.f))
                        app.licznik = 1;
                        app.aktualne_t = 0;
                    end
                    app.C = app.CEditField.Value;
                    app.R = app.REditField.Value;
                    app.Vo = app.VoEditField.Value;
                    if(app.R < 10) || (app.R > 10e7)   
                        uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
                        break;
                    elseif(app.C < 10e-9) || (app.C > 10e-3) 
                        uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
                        break;
                    else
                        liczProstownik(app);
                        rysujWykresy(app);                            
                    end 
                    pause(0.000000000000000000000001);
                end 
                
%                 while(app.aktualne_t <= (app.No/app.f))
%                     app.licznik = app.licznik + 1;
%                     app.aktualne_t = app.aktualne_t + app.delta_t;
%                     if(~app.stopSymulacja)
%                         app.C = app.CEditField.Value;
%                         app.R = app.REditField.Value;
%                         app.Vo = app.VoEditField.Value;
%                         if(app.R < 10) || (app.R > 10e7)   
%                             uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
%                             break;
%                         elseif(app.C < 10e-9) || (app.C > 10e-3) 
%                             uialert(app.GUIprostownikanimacjaUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
%                             break;
%                         else
%                             liczProstownik(app);
%                             rysujWykresy(app);                            
%                         end
%                     else
%                         break;
%                     end                
%                 end 
            end         
            
            app.STARTButton.Enable = "on"; 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stopSymulacja = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIprostownikanimacjaUIFigure = uifigure('Visible', 'off');
            app.GUIprostownikanimacjaUIFigure.Name = 'GUI prostownik animacja';
            app.UAxes = uiaxes(app.GUIprostownikanimacjaUIFigure);
            title(app.UAxes, 'Napiecie')
            xlabel(app.UAxes, 't [s]')
            ylabel(app.UAxes, 'U [V]')
            app.IAxes = uiaxes(app.GUIprostownikanimacjaUIFigure);
            title(app.IAxes, 'Natezenie')
            xlabel(app.IAxes, 't [s]')
            ylabel(app.IAxes, 'I [A]')
            app.VoVLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.VoVLabel.Text = 'Vo [V]';
            app.VoEditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.VoEditField.Value = 12;
            app.fHzLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.fHzLabel.Text = 'f [Hz]';
            app.fEditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.fEditField.Value = 50;
            app.NoEditFieldLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.NoEditFieldLabel.Text = 'No';
            app.NoEditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.NoEditField.Value = 3;
            app.delta_tsEditFieldLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.delta_tsEditFieldLabel.Text = 'delta_t [s]';
            app.delta_tEditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.delta_tEditField.Value = 5e-05;
            app.STARTButton = uibutton(app.GUIprostownikanimacjaUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.GUIprostownikanimacjaUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ROmEditFieldLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.ROmEditFieldLabel.Text = 'R [Om]';
            app.REditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.REditField.Value = 820;
            app.CFLabel = uilabel(app.GUIprostownikanimacjaUIFigure);
            app.CFLabel.Text = 'C [F]';
            app.CEditField = uieditfield(app.GUIprostownikanimacjaUIFigure, 'numeric');
            app.CEditField.Value = 4.7e-05;
            % Show the figure after all components are created
            app.GUIprostownikanimacjaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_prostownik_animacja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIprostownikanimacjaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIprostownikanimacjaUIFigure)
        end
    end
end