classdef GUI_prostownik_animacja2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIprostownikUIFigure   matlab.ui.Figure
        UAxes                   matlab.ui.control.UIAxes
        IAxes                   matlab.ui.control.UIAxes
        VoVLabel                matlab.ui.control.Label
        VoEditField             matlab.ui.control.NumericEditField
        fHzLabel                matlab.ui.control.Label
        fEditField              matlab.ui.control.NumericEditField
        NoEditFieldLabel        matlab.ui.control.Label
        NoEditField             matlab.ui.control.NumericEditField
        delta_tsEditFieldLabel  matlab.ui.control.Label
        delta_tEditField        matlab.ui.control.NumericEditField
        STARTButton             matlab.ui.control.Button
        ROmEditFieldLabel       matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        CFLabel                 matlab.ui.control.Label
        CEditField              matlab.ui.control.NumericEditField
        STOPButton              matlab.ui.control.Button
        WynikiTextAreaLabel     matlab.ui.control.Label
        WynikiTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        R = [];
        Vo = [];
        f = [];
        No = [];
        delta_t = [];
        C = [];  
        
        w = [];
        Vs = [];
        Vr = [];
        Ic = [];
        Ir = [];
        Id = [];
        t = [];
        t_off = [];
        t_on = [];
        idx_t_off = [];
        idx_t_on = [];
        
        wlaczenieDiody = 1; 
        chwileWylaczania = 0;
        chwileWlaczania = 0;
        
        Usr = [];
        Ut = [];
        kt = [];
        
        aktualnaPozycja = 1;
        rysujDalej = true;
        
        obieg = 0;
        
        Vs_plot = []; 
        Vr_plot = [];   
        V_kursor = [];
        
        Ir_plot = [];
        Ic_plot = [];
        Id_plot = [];
        I_kursor = [];
        
        pierwszeUruchomienie = true;
        
    end
    
    methods (Access = private)   

        function lokalny_prostownik(app)  
            if app.obieg > 0
                app.Vr = [app.Vr(1:app.aktualnaPozycja-1), 0, app.Vr(app.aktualnaPozycja:end-1)];
                app.Vs = [app.Vs(1:app.aktualnaPozycja-1), 0, app.Vs(app.aktualnaPozycja:end-1)];
                
                app.Ir = [app.Ir(1:app.aktualnaPozycja-1), 0, app.Ir(app.aktualnaPozycja:end-1)];
                app.Ic = [app.Ic(1:app.aktualnaPozycja-1), 0, app.Ic(app.aktualnaPozycja:end-1)];
                app.Id = [app.Id(1:app.aktualnaPozycja-1), 0, app.Id(app.aktualnaPozycja:end-1)];
            end
            
            app.Vs(app.aktualnaPozycja) = app.Vo*sin(app.w*app.t(app.aktualnaPozycja));
            app.Vr(app.aktualnaPozycja) = app.Vs(app.aktualnaPozycja);
            app.Ir(app.aktualnaPozycja) = app.Vr(app.aktualnaPozycja)/app.R;
            app.Ic(app.aktualnaPozycja) = app.w*app.C*app.Vo*cos(app.w*app.t(app.aktualnaPozycja));
            app.Id(app.aktualnaPozycja) = 0;
            
           if app.wlaczenieDiody && app.Ir(app.aktualnaPozycja)+app.Ic(app.aktualnaPozycja) <= 0
                app.wlaczenieDiody = 0;
                app.chwileWylaczania = app.chwileWylaczania + 1;
                app.t_off(app.chwileWylaczania) = app.t(app.aktualnaPozycja);
                app.idx_t_off(app.chwileWylaczania) = app.aktualnaPozycja;
            end
            if app.wlaczenieDiody==0
                app.Vr(app.aktualnaPozycja) = app.Vo*sin(app.w*app.t_off(app.chwileWylaczania))*exp((app.t_off(app.chwileWylaczania)-app.t(app.aktualnaPozycja))/(app.R*app.C));
                app.Ir(app.aktualnaPozycja) = app.Vr(app.aktualnaPozycja)/app.R;
                app.Ic(app.aktualnaPozycja) = -app.Ir(app.aktualnaPozycja);
            else
                app.Id(app.aktualnaPozycja) = app.Ic(app.aktualnaPozycja)+app.Ir(app.aktualnaPozycja);
            end
            if app.wlaczenieDiody == 0 && app.Vs(app.aktualnaPozycja)>app.Vr(app.aktualnaPozycja)
                app.wlaczenieDiody = 1;
                app.chwileWlaczania = app.chwileWlaczania+1;
                app.t_on(app.chwileWlaczania) = app.t(app.aktualnaPozycja);
                app.idx_t_on(app.chwileWylaczania) = app.aktualnaPozycja;
            end 

        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STOPButton.Enable = "off";  
            
            app.Vs_plot = animatedline(app.UAxes,'Color','b'); 
            app.Vr_plot = animatedline(app.UAxes,'Color','r');
            app.V_kursor = animatedline(app.UAxes,'Color','k');
            
            app.Ir_plot = animatedline(app.IAxes,'Color','r');
            app.Ic_plot = animatedline(app.IAxes,'Color','k');
            app.Id_plot = animatedline(app.IAxes,'Color','b');
            app.I_kursor = animatedline(app.IAxes,'Color','k');
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.rysujDalej = true;
            clearpoints(app.Vs_plot);
            clearpoints(app.Vr_plot);
           
            clearpoints(app.Ir_plot);
            clearpoints(app.Ic_plot);
            clearpoints(app.Id_plot);
            
            app.aktualnaPozycja = 1;
            app.wlaczenieDiody = 1;
            
%             addpoints(app.Vs_plot,app.t(1:app.aktualnaPozycja),app.Vs); 
%             addpoints(app.Vr_plot,app.t(1:app.aktualnaPozycja),app.Vr);
%            
%             addpoints(app.Ir_plot,app.t(1:app.aktualnaPozycja),app.Ir); 
%             addpoints(app.Ic_plot,app.t(1:app.aktualnaPozycja),app.Ic);
%             addpoints(app.Id_plot,app.t(1:app.aktualnaPozycja),app.Id);
            
            app.STARTButton.Enable = "off"; 
            app.STOPButton.Enable = "on";
            
            app.R = app.REditField.Value;
            app.C = app.CEditField.Value;
            app.Vo = app.VoEditField.Value;
            app.f = app.fEditField.Value;
            app.No = app.NoEditField.Value;
            app.delta_t = app.delta_tEditField.Value;
            
            if(app.R < 10) || (app.R > 10e7)   
                uialert(app.GUIprostownikUIFigure,'Wartosc R musi nalezec do przedzialu <10;10^7>','Blad danych!');
            elseif(app.C < 10e-9) || (app.C > 10e-3) 
                uialert(app.GUIprostownikUIFigure,'Wartosc C musi nalezec do przedzialu <10^(-9);10^(-3)>','Blad danych!');
            else
                app.w = 2*pi*app.f;              
                
                app.UAxes.XLim = [0, (app.No/app.f)];
                app.IAxes.XLim = [0, (app.No/app.f)];
                
                minWartoscV = -app.Vo;
                maxWartoscV = app.Vo;
                app.UAxes.YLim = [minWartoscV, maxWartoscV];
                minWartoscI = -app.w*app.C*app.Vo-app.Vo/app.R;
                maxWartoscI = app.w*app.C*app.Vo+app.Vo/app.R;                
                app.IAxes.YLim = [minWartoscI, maxWartoscI];                
                
                app.fEditField.Enable = "off";
                app.NoEditField.Enable = "off";
                app.delta_tEditField.Enable = "off";            

%                 if app.pierwszeUruchomienie
%                     app.t = 0:app.delta_t:(app.No/app.f);
%                     app.pierwszeUruchomienie = false;
%                 end
                
                app.WynikiTextArea.Value = compose("Usr = [V]" + "\n" + "Ut = [V]" + "\n" + "kt = ");                    

                app.t = 0:app.delta_t:(app.No/app.f);
                
                while app.rysujDalej                    
                    app.R = app.REditField.Value;
                    app.C = app.CEditField.Value;
                    app.Vo = app.VoEditField.Value;
                    
                    lokalny_prostownik(app);  
                    
                    clearpoints(app.V_kursor);
                    clearpoints(app.I_kursor);
                    addpoints(app.V_kursor,linspace(app.t(app.aktualnaPozycja),app.t(app.aktualnaPozycja),20),linspace(minWartoscV,maxWartoscV,20));
                    addpoints(app.I_kursor,linspace(app.t(app.aktualnaPozycja),app.t(app.aktualnaPozycja),20),linspace(minWartoscI,maxWartoscI,20));                    
                                        
                     if app.obieg > 0
                       clearpoints(app.Vs_plot);
                       clearpoints(app.Vr_plot);
                       
                       clearpoints(app.Ir_plot);
                       clearpoints(app.Ic_plot);
                       clearpoints(app.Id_plot);
                       
                       addpoints(app.Vs_plot,app.t,app.Vs); 
                       addpoints(app.Vr_plot,app.t,app.Vr);
                       
                       addpoints(app.Ir_plot,app.t,app.Ir); 
                       addpoints(app.Ic_plot,app.t,app.Ic);
                       addpoints(app.Id_plot,app.t,app.Id);
                    else
                        addpoints(app.Vs_plot,app.t(app.aktualnaPozycja),app.Vs(app.aktualnaPozycja));
                        addpoints(app.Vr_plot,app.t(app.aktualnaPozycja),app.Vr(app.aktualnaPozycja));
                        
                        addpoints(app.Ir_plot,app.t(app.aktualnaPozycja),app.Ir(app.aktualnaPozycja));
                        addpoints(app.Ic_plot,app.t(app.aktualnaPozycja),app.Ic(app.aktualnaPozycja));
                        addpoints(app.Id_plot,app.t(app.aktualnaPozycja),app.Id(app.aktualnaPozycja));
                    end
                                             
                    drawnow;                    
                    
                    if app.obieg > 0                        
                        app.Usr = sum(app.Vr(1:app.idx_t_off(1)))/(app.idx_t_off(1));
                        app.Ut = (max(app.Vr(1:app.idx_t_off(1)))-min(app.Vr(1:app.idx_t_off(1))))/2;
                        app.kt = app.Ut/app.Usr; 
                        app.WynikiTextArea.Value = compose("Usr = " + num2str(app.Usr) + " [V]" +"\n" + "Ut = " + num2str(app.Ut) + " [V]" + "\n" + "kt = " + num2str(app.kt)); 
                    end
                    
                    app.aktualnaPozycja = app.aktualnaPozycja + 1;
                    if app.aktualnaPozycja > length(app.t)
                        app.aktualnaPozycja = 1;
                        app.wlaczenieDiody = 1;
                        app.obieg = app.obieg + 1;
                    end
   
                end                
                
                app.STOPButton.Enable = "off";
                
                app.fEditField.Enable = "on";
                app.NoEditField.Enable = "on";
                app.delta_tEditField.Enable = "on";                                  
                
                
%                 app.Usr = sum(app.Vr(app.idx_t_off(1):app.idx_t_off(2)))/(app.idx_t_off(2)-app.idx_t_off(1)+1);
%                 app.Ut = (max(app.Vr(app.idx_t_off(1):app.idx_t_off(2)))-min(app.Vr(app.idx_t_off(1):app.idx_t_off(2))))/2;
%                 app.kt = app.Ut/app.Usr;
%                 uialert(app.GUIprostownikUIFigure,['Dla przedzialu czasu od t_a do t_d napiecie srednie wynosi '...
%                     , num2str(app.Usr),'[V], amplituda tetnien wynosi ', num2str(app.Ut),...
%                     '[V] oraz wspolczynnik tetnien wynosi ', num2str(app.kt),'.'],'Otrzymane wyniki:',...
%                     'Icon','info');
            end
            
            app.STARTButton.Enable = "on"; 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.rysujDalej = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIprostownikUIFigure = uifigure('Visible', 'off');
            app.GUIprostownikUIFigure.Name = 'GUI prostownik';
            app.UAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.UAxes, 'Napiecie')
            xlabel(app.UAxes, 't [s]')
            ylabel(app.UAxes, 'U [V]')
            app.IAxes = uiaxes(app.GUIprostownikUIFigure);
            title(app.IAxes, 'Natezenie')
            xlabel(app.IAxes, 't [s]')
            ylabel(app.IAxes, 'I [A]')
            app.VoVLabel = uilabel(app.GUIprostownikUIFigure);
            app.VoVLabel.Text = 'Vo [V]';
            app.VoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.VoEditField.Value = 12;
            app.fHzLabel = uilabel(app.GUIprostownikUIFigure);
            app.fHzLabel.Text = 'f [Hz]';
            app.fEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.fEditField.Value = 50;
            app.NoEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.NoEditFieldLabel.Text = 'No';
            app.NoEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.NoEditField.Value = 3;
            app.delta_tsEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.delta_tsEditFieldLabel.Text = 'delta_t [s]';
            app.delta_tEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.delta_tEditField.Value = 0.0005;
            app.STARTButton = uibutton(app.GUIprostownikUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ROmEditFieldLabel = uilabel(app.GUIprostownikUIFigure);
            app.ROmEditFieldLabel.Text = 'R [Om]';
            app.REditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.REditField.Value = 820;
            app.CFLabel = uilabel(app.GUIprostownikUIFigure);
            app.CFLabel.Text = 'C [F]';
            app.CEditField = uieditfield(app.GUIprostownikUIFigure, 'numeric');
            app.CEditField.Value = 4.7e-05;
            app.STOPButton = uibutton(app.GUIprostownikUIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.WynikiTextAreaLabel = uilabel(app.GUIprostownikUIFigure);
            app.WynikiTextAreaLabel.Text = 'Wyniki';
            app.WynikiTextArea = uitextarea(app.GUIprostownikUIFigure);
            app.WynikiTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.GUIprostownikUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_prostownik_animacja2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIprostownikUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIprostownikUIFigure)
        end
    end
end